classdef RoterView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        Panel                   matlab.ui.container.Panel
        start                   matlab.ui.control.Button
        Z_aim                   matlab.ui.control.EditField
        ZmLabel                 matlab.ui.control.Label
        Y_aim                   matlab.ui.control.EditField
        YmLabel                 matlab.ui.control.Label
        X_aim                   matlab.ui.control.EditField
        XmEditFieldLabel        matlab.ui.control.Label
        t                       matlab.ui.control.EditField
        tsLabel                 matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        basic_param             matlab.ui.container.Tab
        default_param           matlab.ui.control.Button
        Izz                     matlab.ui.control.EditField
        IzzEditFieldLabel       matlab.ui.control.Label
        Iyy                     matlab.ui.control.EditField
        IyyEditFieldLabel       matlab.ui.control.Label
        Ixx                     matlab.ui.control.EditField
        IxxEditFieldLabel       matlab.ui.control.Label
        L                       matlab.ui.control.EditField
        LmLabel                 matlab.ui.control.Label
        g                       matlab.ui.control.EditField
        gmLabel                 matlab.ui.control.Label
        m                       matlab.ui.control.EditField
        mLabel                  matlab.ui.control.Label
        clear_all_param         matlab.ui.control.Button
        control1_param          matlab.ui.container.Tab
        X_r                     matlab.ui.control.EditField
        rLabel_6                matlab.ui.control.Label
        X_h                     matlab.ui.control.EditField
        hLabel_6                matlab.ui.control.Label
        Y_r                     matlab.ui.control.EditField
        rLabel_5                matlab.ui.control.Label
        Y_h                     matlab.ui.control.EditField
        hLabel_5                matlab.ui.control.Label
        yaw_r                   matlab.ui.control.EditField
        rLabel_4                matlab.ui.control.Label
        yaw_h                   matlab.ui.control.EditField
        hLabel_4                matlab.ui.control.Label
        roll_r                  matlab.ui.control.EditField
        rLabel_3                matlab.ui.control.Label
        roll_h                  matlab.ui.control.EditField
        hLabel_3                matlab.ui.control.Label
        Z_r                     matlab.ui.control.EditField
        rLabel_2                matlab.ui.control.Label
        Z_h                     matlab.ui.control.EditField
        hLabel_2                matlab.ui.control.Label
        default_param_2         matlab.ui.control.Button
        clear_all_param_2       matlab.ui.control.Button
        pitch_r                 matlab.ui.control.EditField
        rLabel                  matlab.ui.control.Label
        pitch_h                 matlab.ui.control.EditField
        hLabel                  matlab.ui.control.Label
        CenterPanel             matlab.ui.container.Panel
        fig_3d                  matlab.ui.control.UIAxes
        fig_2d                  matlab.ui.control.UIAxes
        RightPanel              matlab.ui.container.Panel
        HeadingIndicator        Aero.ui.control.HeadingIndicator
        HeadingIndicatorLabel   matlab.ui.control.Label
        ArtificialHorizon       Aero.ui.control.ArtificialHorizon
        ArtificialHorizonLabel  matlab.ui.control.Label
        AirspeedIndicator       Aero.ui.control.AirspeedIndicator
        AirspeedIndicatorLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: clear_all_param, clear_all_param_2
        function clear_all_paramButtonPushed(app, event)
            app.m.Value ='';
            app.g.Value ='';
            app.L.Value ='';
            app.Ixx.Value ='';
            app.Iyy.Value ='';
            app.Izz.Value ='';
            app.pitch_h.Value ='';
            app.pitch_r.Value ='';
            app.roll_h.Value ='';
            app.roll_r.Value ='';
            app.yaw_h.Value = '';
            app.yaw_r.Value = '';
            app.X_h.Value = '';
            app.X_r.Value = '';
            app.Y_h.Value = '';
            app.Y_r.Value = '';
            app.Z_h.Value = '';
            app.Z_r.Value = '';
        end

        % Button pushed function: default_param, default_param_2
        function default_paramButtonPushed(app, event)
            app.m.Value ='0.547';
            app.g.Value ='9.8';
            app.L.Value ='0.2';
            app.Ixx.Value ='0.0033';
            app.Iyy.Value ='0.0033';
            app.Izz.Value ='0.0058';
            app.pitch_h.Value ='0.01';
            app.pitch_r.Value ='180';
            app.roll_h.Value ='0.01';
            app.roll_r.Value ='180';
            app.yaw_h.Value = '0.008';
            app.yaw_r.Value = '100';
            app.X_h.Value = '0.01';
            app.X_r.Value = '300';
            app.Y_h.Value = '0.01';
            app.Y_r.Value = '300';
            app.Z_h.Value = '0.01';
            app.Z_r.Value = '180';
        end

        % Button pushed function: start
        function startButtonPushed(app, event)

            % 飞机参数
            m = str2num(app.m.Value);
            g = str2num(app.g.Value);
            L = str2num(app.L.Value);
            Ixx = str2num(app.Ixx.Value);
            Iyy = str2num(app.Iyy.Value);
            Izz = str2num(app.Izz.Value);

            % 仿真任务参数
            X_aim = str2num(app.X_aim.Value);
            Y_aim = str2num(app.Y_aim.Value);
            Z_aim = str2num(app.Z_aim.Value);
            stoptime = str2num(app.t.Value);

            % 仿真设置、运行和结果
            options = simset('SrcWorkspace','current');
            sim('ADRC',stoptime,options);

            % 2D作图
            cla(app.fig_2d); 
            xout_interp = interp1(linspace(0,1,length(xout)), xout, linspace(0,1,length(tout)));
            yout_interp = interp1(linspace(0,1,length(yout)), yout, linspace(0,1,length(tout)));
            zout_interp = interp1(linspace(0,1,length(zout)), zout, linspace(0,1,length(tout)));

            plot(app.fig_2d, tout, xout_interp, 'b', 'LineWidth', 1.5);
            hold(app.fig_2d, 'on'); 
            plot(app.fig_2d, tout, yout_interp, 'r', 'LineWidth', 1.5); 
            plot(app.fig_2d, tout, zout_interp, 'g', 'LineWidth', 1.5); 

            legend(app.fig_2d, 'X方向位置', 'Y方向位置', 'Z方向位置');
            xlabel(app.fig_2d, 't(s)');
            ylabel(app.fig_2d, '位置(m)');
            title(app.fig_2d, '位置随时间变化');
            grid(app.fig_2d, 'on');

            % 三维动画
            cla(app.fig_3d);
            grid(app.fig_3d,'on'); 
            box(app.fig_3d,'on');
            hold(app.fig_3d,'on');
            xlabel(app.fig_3d,'X/m');
            ylabel(app.fig_3d,'Y/m');
            zlabel(app.fig_3d,'Z/m');
            title(app.fig_3d,'飞行三维轨迹');
            view(app.fig_3d,3);  % 三维视角
            trajLine = plot3(app.fig_3d, xout(1), yout(1), zout(1), 'b-', 'LineWidth', 1.5);
            posPoint = plot3(app.fig_3d, xout(1), yout(1), zout(1), 'ro', 'MarkerFaceColor','r');
            axis(app.fig_3d, [min(xout)-0.5, max(xout)+0.5, ...
                   min(yout)-0.5, max(yout)+0.5, ...
                   min(zout)-0.5, max(zout)+0.5]);
            for k = 2:length(xout)
                set(trajLine, 'XData', xout(1:k), 'YData', yout(1:k), 'ZData', zout(1:k));
                set(posPoint, 'XData', xout(k), 'YData', yout(k), 'ZData', zout(k));
                drawnow limitrate;
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.basic_param = uitab(app.TabGroup);
            app.basic_param.Title = '飞机参数';
            app.clear_all_param = uibutton(app.basic_param, 'push');
            app.clear_all_param.ButtonPushedFcn = createCallbackFcn(app, @clear_all_paramButtonPushed, true);
            app.clear_all_param.Text = '全部清除';
            app.mLabel = uilabel(app.basic_param);
            app.mLabel.Text = '质量m';
            app.m = uieditfield(app.basic_param, 'text');
            app.gmLabel = uilabel(app.basic_param);
            app.gmLabel.Text = '重力加速度g';
            app.g = uieditfield(app.basic_param, 'text');
            app.LmLabel = uilabel(app.basic_param);
            app.LmLabel.Text = '臂长L (m)';
            app.L = uieditfield(app.basic_param, 'text');
            app.IxxEditFieldLabel = uilabel(app.basic_param);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.Ixx = uieditfield(app.basic_param, 'text');
            app.IyyEditFieldLabel = uilabel(app.basic_param);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.Iyy = uieditfield(app.basic_param, 'text');
            app.IzzEditFieldLabel = uilabel(app.basic_param);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.Izz = uieditfield(app.basic_param, 'text');
            app.default_param = uibutton(app.basic_param, 'push');
            app.default_param.ButtonPushedFcn = createCallbackFcn(app, @default_paramButtonPushed, true);
            app.default_param.Text = '默认参数';
            app.control1_param = uitab(app.TabGroup);
            app.control1_param.Title = '跟踪微分器';
            app.hLabel = uilabel(app.control1_param);
            app.hLabel.Text = '俯仰 h';
            app.pitch_h = uieditfield(app.control1_param, 'text');
            app.rLabel = uilabel(app.control1_param);
            app.rLabel.Text = '俯仰 r';
            app.pitch_r = uieditfield(app.control1_param, 'text');
            app.clear_all_param_2 = uibutton(app.control1_param, 'push');
            app.clear_all_param_2.ButtonPushedFcn = createCallbackFcn(app, @clear_all_paramButtonPushed, true);
            app.clear_all_param_2.Text = '全部清除';
            app.default_param_2 = uibutton(app.control1_param, 'push');
            app.default_param_2.ButtonPushedFcn = createCallbackFcn(app, @default_paramButtonPushed, true);
            app.default_param_2.Text = '默认参数';
            app.hLabel_2 = uilabel(app.control1_param);
            app.hLabel_2.Text = 'Z方向 h';
            app.Z_h = uieditfield(app.control1_param, 'text');
            app.rLabel_2 = uilabel(app.control1_param);
            app.rLabel_2.Text = 'Z方向 r';
            app.Z_r = uieditfield(app.control1_param, 'text');
            app.hLabel_3 = uilabel(app.control1_param);
            app.hLabel_3.Text = '滚转 h';
            app.roll_h = uieditfield(app.control1_param, 'text');
            app.rLabel_3 = uilabel(app.control1_param);
            app.rLabel_3.Text = '滚转 r';
            app.roll_r = uieditfield(app.control1_param, 'text');
            app.hLabel_4 = uilabel(app.control1_param);
            app.hLabel_4.Text = '偏航 h';
            app.yaw_h = uieditfield(app.control1_param, 'text');
            app.rLabel_4 = uilabel(app.control1_param);
            app.rLabel_4.Text = '偏航 r';
            app.yaw_r = uieditfield(app.control1_param, 'text');
            app.hLabel_5 = uilabel(app.control1_param);
            app.hLabel_5.Text = 'Y方向 h';
            app.Y_h = uieditfield(app.control1_param, 'text');
            app.rLabel_5 = uilabel(app.control1_param);
            app.rLabel_5.Text = 'Y方向 r';
            app.Y_r = uieditfield(app.control1_param, 'text');
            app.hLabel_6 = uilabel(app.control1_param);
            app.hLabel_6.Text = 'X方向 h';
            app.X_h = uieditfield(app.control1_param, 'text');
            app.rLabel_6 = uilabel(app.control1_param);
            app.rLabel_6.Text = 'X方向 r';
            app.X_r = uieditfield(app.control1_param, 'text');
            app.Panel = uipanel(app.GridLayout2);
            app.Panel.Title = '仿真任务';
            app.tsLabel = uilabel(app.Panel);
            app.tsLabel.Text = '仿真时间t (s)';
            app.t = uieditfield(app.Panel, 'text');
            app.XmEditFieldLabel = uilabel(app.Panel);
            app.XmEditFieldLabel.Text = 'X位置指令 (m)';
            app.X_aim = uieditfield(app.Panel, 'text');
            app.YmLabel = uilabel(app.Panel);
            app.YmLabel.Text = 'Y位置指令 (m)';
            app.Y_aim = uieditfield(app.Panel, 'text');
            app.ZmLabel = uilabel(app.Panel);
            app.ZmLabel.Text = 'Z位置指令 (m)';
            app.Z_aim = uieditfield(app.Panel, 'text');
            app.start = uibutton(app.Panel, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = '开始仿真';
            app.CenterPanel = uipanel(app.GridLayout);
            app.fig_2d = uiaxes(app.CenterPanel);
            title(app.fig_2d, '位置随时间变化')
            xlabel(app.fig_2d, 'X')
            ylabel(app.fig_2d, 'Y')
            zlabel(app.fig_2d, 'Z')
            app.fig_3d = uiaxes(app.CenterPanel);
            title(app.fig_3d, '飞行三维轨迹')
            xlabel(app.fig_3d, 'X')
            ylabel(app.fig_3d, 'Y')
            zlabel(app.fig_3d, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.AirspeedIndicatorLabel = uilabel(app.RightPanel);
            app.AirspeedIndicatorLabel.Text = 'Airspeed Indicator';
            app.AirspeedIndicator = uiaeroairspeed(app.RightPanel);
            app.ArtificialHorizonLabel = uilabel(app.RightPanel);
            app.ArtificialHorizonLabel.Text = 'ArtificialHorizon';
            app.ArtificialHorizon = uiaerohorizon(app.RightPanel);
            app.HeadingIndicatorLabel = uilabel(app.RightPanel);
            app.HeadingIndicatorLabel.Text = 'HeadingIndicator';
            app.HeadingIndicator = uiaeroheading(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoterView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end